classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        RiceTypeEditFieldLabel  matlab.ui.control.Label
        RiceTypeEditField       matlab.ui.control.EditField
        QualityEditFieldLabel   matlab.ui.control.Label
        QualityEditField        matlab.ui.control.EditField
        UploadButton            matlab.ui.control.Button
        preprocessButton        matlab.ui.control.Button
        classifyButton          matlab.ui.control.Button
        QualityTestButton       matlab.ui.control.Button
        OUTPUTLabel             matlab.ui.control.Label
        QualityTestingofRiceGrainandClassificationLabel  matlab.ui.control.Label
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes4                 matlab.ui.control.UIAxes
        BinarizedImageLabel     matlab.ui.control.Label
        GrayImageLabel          matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            global image
            [filename, filepath] = uigetfile({'*.jpg;*.png', 'Image Files'}, 'Select an image');
            image = imread([filepath, filename]);

            
            imshow(image,'Parent',app.UIAxes4);

        end

        % Button pushed function: preprocessButton
        function preprocessButtonPushed(app, event)
            global image filteredImage
            image = imresize(image, [224 224]);
            
            grayImage = rgb2gray(image);
            enhancedImage = imadjust(grayImage);
            binaryImage = imbinarize(enhancedImage, 'adaptive', 'Sensitivity', 0.5);
            filteredImage = bwareaopen(binaryImage, 100);
            imshow(binaryImage,'Parent',app.UIAxes3);
            imshow(grayImage,'Parent',app.UIAxes2);
        end

        % Button pushed function: classifyButton
        function classifyButtonPushed(app, event)
           global image
           load("trainedNetwork_1.mat");
           net = trainedNetwork_1;
           %[filename, filepath] = uigetfile({'*.jpg;*.png', 'Image Files'}, 'Select an image');
           %I = imread([filepath, filename]);
         
           I = imresize(image,[224 224]);
           
           label = classify(net, I); 
           app.RiceTypeEditField.Value = char(label);
            
            
        end

        % Button pushed function: QualityTestButton
        function QualityTestButtonPushed(app, event)
            global image filteredImage


se = strel('disk', 3);
morphedImage = imclose(filteredImage, se);


grainStats = regionprops(morphedImage, 'Area', 'Perimeter', 'Eccentricity');

quality=0;
notquality=0;
for i = 1:numel(grainStats)
    area = grainStats(i).Area;
    perimeter = grainStats(i).Perimeter;
    eccentricity = grainStats(i).Eccentricity;


    if area > 80 && perimeter > 20 && eccentricity < 0.8
        disp(['Grain ', num2str(i), ' is of high quality.']);
        quality = quality+1;
    else
        disp(['Grain ', num2str(i), ' does not meet quality standards.']);
        notquality =notquality+1;
    end
end

if quality>=notquality
     app.QualityEditField.Value = char("Good quality");
else
    app.QualityEditField.Value = char("Bad Quality");
    
end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RiceTypeEditFieldLabel = uilabel(app.UIFigure);
            app.RiceTypeEditFieldLabel.Text = 'Rice Type';
            app.RiceTypeEditField = uieditfield(app.UIFigure, 'text');
            app.RiceTypeEditField.Tag = 'edit1';
            app.QualityEditFieldLabel = uilabel(app.UIFigure);
            app.QualityEditFieldLabel.Text = 'Quality';
            app.QualityEditField = uieditfield(app.UIFigure, 'text');
            app.QualityEditField.Tag = 'edit2';
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.preprocessButton = uibutton(app.UIFigure, 'push');
            app.preprocessButton.ButtonPushedFcn = createCallbackFcn(app, @preprocessButtonPushed, true);
            app.preprocessButton.Text = 'preprocess';
            app.classifyButton = uibutton(app.UIFigure, 'push');
            app.classifyButton.ButtonPushedFcn = createCallbackFcn(app, @classifyButtonPushed, true);
            app.classifyButton.Text = 'classify';
            app.QualityTestButton = uibutton(app.UIFigure, 'push');
            app.QualityTestButton.ButtonPushedFcn = createCallbackFcn(app, @QualityTestButtonPushed, true);
            app.QualityTestButton.Text = 'Quality Test';
            app.OUTPUTLabel = uilabel(app.UIFigure);
            app.OUTPUTLabel.Text = 'OUTPUT';
            app.QualityTestingofRiceGrainandClassificationLabel = uilabel(app.UIFigure);
            app.QualityTestingofRiceGrainandClassificationLabel.Text = 'Quality Testing of Rice Grain and Classification';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, '')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UIAxes4.XTick = [];
            app.UIAxes4.XTickLabel = '';
            app.UIAxes4.YTick = [];
            app.BinarizedImageLabel = uilabel(app.UIFigure);
            app.BinarizedImageLabel.Text = 'Binarized Image';
            app.GrayImageLabel = uilabel(app.UIFigure);
            app.GrayImageLabel.Text = 'Gray Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end